classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FichierMenu                     matlab.ui.container.Menu
        ImportItem                      matlab.ui.container.Menu
        ExportItem                      matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        ParamtrescodebarresTab          matlab.ui.container.Tab
        DfinitiondesanglesLabel         matlab.ui.control.Label
        ParamtresPanel                  matlab.ui.container.Panel
        Angleducodebarresautourdey_cdegEditField  matlab.ui.control.NumericEditField
        Angleducodebarresautourdey_cdegEditFieldLabel  matlab.ui.control.Label
        DistancezducodebarrescmEditField  matlab.ui.control.NumericEditField
        DistancezducodebarrescmEditFieldLabel  matlab.ui.control.Label
        DistancexducodebarrescmEditField  matlab.ui.control.NumericEditField
        DistancexducodebarrescmEditFieldLabel  matlab.ui.control.Label
        Angleducodebarresautourdez_cdegEditField  matlab.ui.control.NumericEditField
        AnglehorizontalducodebarresdegrsLabel  matlab.ui.control.Label
        RflectivitdelasurfaceSlider_2   matlab.ui.control.Slider
        RflectivitdelasurfaceSlider_2Label  matlab.ui.control.Label
        Angleducodebarresautourdexphi_cdegEditField  matlab.ui.control.NumericEditField
        Angleverticalducodebarres_cdegLabel  matlab.ui.control.Label
        RayondecourburecmLabel          matlab.ui.control.Label
        RayondecourburecmEditField_2    matlab.ui.control.NumericEditField
        Image_3                         matlab.ui.control.Image
        SimulationTab                   matlab.ui.container.Tab
        TempsdelectureEditField         matlab.ui.control.NumericEditField
        TempsdelectureEditFieldLabel    matlab.ui.control.Label
        InsrerlecodebarresvirguleEditField  matlab.ui.control.EditField
        InsrerlecodebarresvirguleEditFieldLabel  matlab.ui.control.Label
        ParamtresPanel_2                matlab.ui.container.Panel
        FrquencedecoupurefiltresphotodiodeEditField  matlab.ui.control.NumericEditField
        FiltreconvertisseurcouranttensionconstantedetempsLabel  matlab.ui.control.Label
        CoeffdinertiedusystmeEditField  matlab.ui.control.NumericEditField
        CoeffdinertiedusystmeEditFieldLabel  matlab.ui.control.Label
        AmplitudedelasourceEditField    matlab.ui.control.NumericEditField
        AmplitudedelasourceLabel        matlab.ui.control.Label
        FrquencedelasourceradsEditField  matlab.ui.control.NumericEditField
        FrquencedelasourceLabel         matlab.ui.control.Label
        PuissancedufaisceauI_0EditField  matlab.ui.control.NumericEditField
        PuissancedufaisceauI_0Label     matlab.ui.control.Label
        Tailledufaisceaulaserw_0EditField  matlab.ui.control.NumericEditField
        TailledufaisceaulaserLabel      matlab.ui.control.Label
        CoeffdefrottementdusystmeEditField  matlab.ui.control.NumericEditField
        CoeffdefrottementdugalvoLabel   matlab.ui.control.Label
        DmarrerlasimulationButton       matlab.ui.control.Button
        TempsdesimulationSlider         matlab.ui.control.Slider
        TempsdesimulationSliderLabel    matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        VisualisationTab                matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        PhotodiodebrutTab               matlab.ui.container.Tab
        UIAxesPhotoRaw                  matlab.ui.control.UIAxes
        GalvanomtresTab                 matlab.ui.container.Tab
        Image_2                         matlab.ui.control.Image
        FactureTab                      matlab.ui.container.Tab
        SauvegarderlafactureButton      matlab.ui.control.Button
        AjoutmanueldarticlesButton      matlab.ui.control.Button
        AjoutmanuelEditField            matlab.ui.control.EditField
        DerniercodebarresluEditField    matlab.ui.control.EditField
        DerniercodebarresluLabel        matlab.ui.control.Label
        TotalEditField                  matlab.ui.control.NumericEditField
        TotalLabel                      matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
    end

    
    properties (Access = private)
        variables = {'dx_c', 'dz_c',"freq","filtre", "amp","phi_c", 'alpha_c',"theta_c","courbure","ref", 'barres', 'J_s',"F_s", "I_0", "w_0"}
        t % facture de base
        total % coût total
        Clock1
        Clock2
        temps % temps écoulé 
    end

    methods (Access = private)
        function WriteVarsToWorkspace(app,ws)
            assignin(ws, "dx_c",app.DistancexducodebarrescmEditField.Value);
            assignin(ws, "dz_c",app.DistancezducodebarrescmEditField.Value);
            assignin(ws, "alpha_c",app.Angleducodebarresautourdey_cdegEditField.Value);
            assignin(ws, "theta_c",app.Angleducodebarresautourdez_cdegEditField.Value);
            assignin(ws, "phi_c",app.Angleducodebarresautourdexphi_cdegEditField.Value);
            assignin(ws, "J_s",app.CoeffdinertiedusystmeEditField.Value);
            assignin(ws, "F_s",app.CoeffdefrottementdusystmeEditField.Value);
            assignin(ws, "I_0",app.PuissancedufaisceauI_0EditField.Value);
            assignin(ws, "w_0", app.Tailledufaisceaulaserw_0EditField.Value);
            assignin(ws,"ref", app.RflectivitdelasurfaceSlider_2.Value);
            assignin(ws,"courbure", app.RayondecourburecmEditField_2.Value);
            assignin(ws,"amp", app.AmplitudedelasourceEditField.Value);
            assignin(ws,"freq", app.FrquencedelasourceradsEditField.Value);
            assignin(ws,"barres", app.InsrerlecodebarresvirguleEditField.Value);
            assignin(ws,"filtre", app.FrquencedecoupurefiltresphotodiodeEditField.Value);
        end
        
        function ReadVarsFromNamespace(app,ns)
            app.DistancexducodebarrescmEditField.Value = ns.dx_c;
            app.DistancezducodebarrescmEditField.Value = ns.dz_c;
            app.InsrerlecodebarresvirguleEditField.Value = ns.barres;
            app.CoeffdinertiedusystmeEditField.Value = ns.J_s;
            app.CoeffdefrottementdusystmeEditField.Value = ns.F_s;
            app.PuissancedufaisceauI_0EditField.Value = ns.I_0;
            app.Tailledufaisceaulaserw_0EditField.Value = ns.w_0;
            app.Angleducodebarresautourdez_cdegEditField.Value = ns.theta_c;
            app.Angleducodebarresautourdey_cdegEditField.Value = ns.alpha_c;
            app.Angleducodebarresautourdexphi_cdegEditField.Value = ns.phi_c;
            app.RayondecourburecmEditField_2.Value = ns.courbure;
            app.RflectivitdelasurfaceSlider_2.Value = ns.ref;
            app.FrquencedelasourceradsEditField.Value = ns.freq;
            app.AmplitudedelasourceEditField.Value = ns.amp;
            app.FrquencedecoupurefiltresphotodiodeEditField.Value = ns.filtre;
        end
        
        function DisplayGraphs(app,simOut)
            %GalvoVals = simOut.ScopeGalvo.Values;
            PhotoRaw = simOut.ScopePhotoRaw{1}.Values.Data;
            PhotoBruitRaw = simOut.ScopePhotoRaw{2}.Values.Data;
            Photo = simOut.ScopePhoto{1}.Values.Data;
            Time = simOut.ScopePhoto{1}.Values.Time;
            
            %hold(app.UIAxesPhotoRaw, 'on')
            %hold on
            plot(app.UIAxesPhotoRaw, Time, PhotoBruitRaw)
            hold(app.UIAxesPhotoRaw, 'on')
            plot(app.UIAxesPhotoRaw, Time, PhotoRaw)
            legend(app.UIAxesPhotoRaw, 'Signal bruité','Signal non-bruité')
            %plot(app.UIAxesPhoto, Time, Photo)
            hold(app.UIAxesPhotoRaw, 'off')
            %hold off
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: DmarrerlasimulationButton, UITable
        function DmarrerlasimulationButtonPushed(app, event)
            app.Clock1 = clock;
            [y,fs]=audioread('Barcode scanner beep sound (sound effect).mp3');
            CODE=str2num(app.InsrerlecodebarresvirguleEditField.Value);
            assignin("base","CODE",CODE);
            WriteVarsToWorkspace(app, 'base');
            simOut=sim("Frame.slx", "StopTime",num2str(app.TempsdesimulationSlider.Value));
            DisplayGraphs(app,simOut);
            code=simOut.code;
            code=transpose(code);
            for i=1:(100)
                code=[code;code(1,:)];
            end
            for i=210:300:length(code)
                if i+300 > length(code)
                    break
                end
                code1= code(:,i:i+300);
            pas=((i-200)/300)+1;
               name = ['code' num2str(pas) '.png'];
                saveas(imshow(code1),name);
            I = imread(name);
            msg = readBarcode(I);
            
            if strlength(msg)==12
                break
            end
            end
            lecture=strcat("0", msg);
        assignin("base","lecture", lecture)

        filename = "upcPrice.txt";
    
        opts = detectImportOptions(filename, 'NumVariables', 4);
        opts.VariableNames(1) = {'Code'};
        opts.VariableNames(2) = {'Quantite'};
        opts.VariableNames(3) = {'Desc'};
        opts.VariableNames(4) = {'Prix'};
    
        db = readtable(filename, opts);
        codeNum = str2double(strrep(lecture, ' ', ''));
        row = db(ismember(db.Code, codeNum),:);
        row(:,1)=[];
        assignin("base","row",row)

        if strlength(lecture)==13
           sound(y,fs)
        app.UITable.Data = [app.t;row];
        app.t=app.UITable.Data;
        app.DerniercodebarresluEditField.Value = lecture;

        app.total = app.TotalEditField.Value;
        app.TotalEditField.Value =app.total + row.Prix;
        app.total = app.TotalEditField.Value;
        app.Clock2 = clock;
        app.temps = etime(app.Clock2,app.Clock1);
        app.TempsdelectureEditField.Value = app.temps;
        end
        end

        % Menu selected function: ExportItem
        function ExportItemMenuSelected(app, event)
            [file,path] = uiputfile('*.mat');
            WriteVarsToWorkspace(app, 'caller');
            filepath = fullfile(path,file);
            save(filepath, app.variables{:});
        end

        % Menu selected function: ImportItem
        function ImportItemMenuSelected(app, event)
            [file,path] = uigetfile('*.mat');
            if ~isequal(file,0)
                filepath = fullfile(path,file);
                ns = load(filepath, app.variables{:});
                ReadVarsFromNamespace(app,ns);
            end
        end

        % Button pushed function: AjoutmanueldarticlesButton
        function AjoutmanueldarticlesButtonPushed(app, event)
        lecture=app.AjoutmanuelEditField.Value;
        assignin("base","lecture",lecture);
        filename = "upcPrice.txt";
    
        opts = detectImportOptions(filename, 'NumVariables', 4);
        opts.VariableNames(1) = {'Code'};
        opts.VariableNames(2) = {'Quantite'};
        opts.VariableNames(3) = {'Desc'};
        opts.VariableNames(4) = {'Prix'};
    
        db = readtable(filename, opts);

        codeNum = str2double(strrep(lecture, ' ', ''));
        row = db(ismember(db.Code, codeNum),:);
        row(:,1)=[];
        assignin("base","row",row)
        app.UITable.Data = [app.t;row];
        app.t=app.UITable.Data;
        app.DerniercodebarresluEditField.Value = lecture;

        app.total = app.TotalEditField.Value;
        app.TotalEditField.Value =app.total + row.Prix;
        app.total = app.TotalEditField.Value;
        [y,fs]=audioread('Barcode scanner beep sound (sound effect).mp3');
        sound(y,fs)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FichierMenu = uimenu(app.UIFigure);
            app.FichierMenu.Text = 'Fichier';
            app.ImportItem = uimenu(app.FichierMenu);
            app.ImportItem.MenuSelectedFcn = createCallbackFcn(app, @ImportItemMenuSelected, true);
            app.ImportItem.Text = 'Importer un fichier de configuration';
            app.ExportItem = uimenu(app.FichierMenu);
            app.ExportItem.MenuSelectedFcn = createCallbackFcn(app, @ExportItemMenuSelected, true);
            app.ExportItem.Text = 'Exporter la configuration courante';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParamtrescodebarresTab = uitab(app.TabGroup);
            app.ParamtrescodebarresTab.Title = 'Paramètres code-barres';
            app.Image_3 = uiimage(app.ParamtrescodebarresTab);
            app.Image_3.ImageSource = 'def_angles.png';
            app.ParamtresPanel = uipanel(app.ParamtrescodebarresTab);
            app.ParamtresPanel.Title = 'Paramètres';
            app.RayondecourburecmEditField_2 = uieditfield(app.ParamtresPanel, 'numeric');
            app.RayondecourburecmLabel = uilabel(app.ParamtresPanel);
            app.RayondecourburecmLabel.Text = 'Rayon de courbure [cm]:';
            app.Angleverticalducodebarres_cdegLabel = uilabel(app.ParamtresPanel);
            app.Angleverticalducodebarres_cdegLabel.Text = 'Angle du code-barres autour de x (phi_c) [deg] : ';
            app.Angleducodebarresautourdexphi_cdegEditField = uieditfield(app.ParamtresPanel, 'numeric');
            app.RflectivitdelasurfaceSlider_2Label = uilabel(app.ParamtresPanel);
            app.RflectivitdelasurfaceSlider_2Label.Text = 'Réflectivité de la surface:';
            app.RflectivitdelasurfaceSlider_2 = uislider(app.ParamtresPanel);
            app.RflectivitdelasurfaceSlider_2.Limits = [0 1];
            app.RflectivitdelasurfaceSlider_2.Value = 0.5;
            app.AnglehorizontalducodebarresdegrsLabel = uilabel(app.ParamtresPanel);
            app.AnglehorizontalducodebarresdegrsLabel.Text = 'Angle du code-barres autour de z (θ_c) [deg] : ';
            app.Angleducodebarresautourdez_cdegEditField = uieditfield(app.ParamtresPanel, 'numeric');
            app.DistancexducodebarrescmEditFieldLabel = uilabel(app.ParamtresPanel);
            app.DistancexducodebarrescmEditFieldLabel.Text = 'Distance x du code-barres [cm] :';
            app.DistancexducodebarrescmEditField = uieditfield(app.ParamtresPanel, 'numeric');
            app.DistancezducodebarrescmEditFieldLabel = uilabel(app.ParamtresPanel);
            app.DistancezducodebarrescmEditFieldLabel.Text = 'Distance z du code-barres [cm] :';
            app.DistancezducodebarrescmEditField = uieditfield(app.ParamtresPanel, 'numeric');
            app.Angleducodebarresautourdey_cdegEditFieldLabel = uilabel(app.ParamtresPanel);
            app.Angleducodebarresautourdey_cdegEditFieldLabel.Text = 'Angle du code-barres autour de y (α_c) [deg] : ';
            app.Angleducodebarresautourdey_cdegEditField = uieditfield(app.ParamtresPanel, 'numeric');
            app.DfinitiondesanglesLabel = uilabel(app.ParamtrescodebarresTab);
            app.DfinitiondesanglesLabel.Text = 'Définition des angles';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.Image = uiimage(app.SimulationTab);
            app.Image.ImageSource = 'ULAVAL.png';
            app.TempsdesimulationSliderLabel = uilabel(app.SimulationTab);
            app.TempsdesimulationSliderLabel.Text = ' Temps de simulation';
            app.TempsdesimulationSlider = uislider(app.SimulationTab);
            app.TempsdesimulationSlider.Limits = [5 30];
            app.TempsdesimulationSlider.Value = 5;
            app.DmarrerlasimulationButton = uibutton(app.SimulationTab, 'push');
            app.DmarrerlasimulationButton.ButtonPushedFcn = createCallbackFcn(app, @DmarrerlasimulationButtonPushed, true);
            app.DmarrerlasimulationButton.Text = 'Démarrer la simulation';
            app.ParamtresPanel_2 = uipanel(app.SimulationTab);
            app.ParamtresPanel_2.Title = 'Paramètres';
            app.CoeffdefrottementdugalvoLabel = uilabel(app.ParamtresPanel_2);
            app.CoeffdefrottementdugalvoLabel.Text = 'Coeff. de frottement du système :';
            app.CoeffdefrottementdusystmeEditField = uieditfield(app.ParamtresPanel_2, 'numeric');
            app.TailledufaisceaulaserLabel = uilabel(app.ParamtresPanel_2);
            app.TailledufaisceaulaserLabel.Text = 'Taille du faisceau laser (w_0):';
            app.Tailledufaisceaulaserw_0EditField = uieditfield(app.ParamtresPanel_2, 'numeric');
            app.Tailledufaisceaulaserw_0EditField.Limits = [0 1];
            app.PuissancedufaisceauI_0Label = uilabel(app.ParamtresPanel_2);
            app.PuissancedufaisceauI_0Label.Text = 'Puissance du faisceau (I_0) :';
            app.PuissancedufaisceauI_0EditField = uieditfield(app.ParamtresPanel_2, 'numeric');
            app.FrquencedelasourceLabel = uilabel(app.ParamtresPanel_2);
            app.FrquencedelasourceLabel.Text = 'Fréquence de la source [rad/s]:';
            app.FrquencedelasourceradsEditField = uieditfield(app.ParamtresPanel_2, 'numeric');
            app.AmplitudedelasourceLabel = uilabel(app.ParamtresPanel_2);
            app.AmplitudedelasourceLabel.Text = 'Amplitude de la source:';
            app.AmplitudedelasourceEditField = uieditfield(app.ParamtresPanel_2, 'numeric');
            app.CoeffdinertiedusystmeEditFieldLabel = uilabel(app.ParamtresPanel_2);
            app.CoeffdinertiedusystmeEditFieldLabel.Text = 'Coeff. d''inertie du système :';
            app.CoeffdinertiedusystmeEditField = uieditfield(app.ParamtresPanel_2, 'numeric');
            app.FiltreconvertisseurcouranttensionconstantedetempsLabel = uilabel(app.ParamtresPanel_2);
            app.FiltreconvertisseurcouranttensionconstantedetempsLabel.Text = 'Fréquence de coupure filtres photodiode';
            app.FrquencedecoupurefiltresphotodiodeEditField = uieditfield(app.ParamtresPanel_2, 'numeric');
            app.InsrerlecodebarresvirguleEditFieldLabel = uilabel(app.SimulationTab);
            app.InsrerlecodebarresvirguleEditFieldLabel.Text = 'Insérer le code-barres (virgule)';
            app.InsrerlecodebarresvirguleEditField = uieditfield(app.SimulationTab, 'text');
            app.TempsdelectureEditFieldLabel = uilabel(app.SimulationTab);
            app.TempsdelectureEditFieldLabel.Text = 'Temps de lecture';
            app.TempsdelectureEditField = uieditfield(app.SimulationTab, 'numeric');
            app.VisualisationTab = uitab(app.TabGroup);
            app.VisualisationTab.Title = 'Visualisation';
            app.Image_2 = uiimage(app.VisualisationTab);
            app.Image_2.ImageSource = 'ULAVAL.png';
            app.TabGroup2 = uitabgroup(app.VisualisationTab);
            app.PhotodiodebrutTab = uitab(app.TabGroup2);
            app.PhotodiodebrutTab.Title = 'Photodiode brut';
            app.UIAxesPhotoRaw = uiaxes(app.PhotodiodebrutTab);
            title(app.UIAxesPhotoRaw, 'Signaux bruts de la photodiode en fonction du temps')
            xlabel(app.UIAxesPhotoRaw, 'Temps [s]')
            ylabel(app.UIAxesPhotoRaw, 'Courant [A]')
            zlabel(app.UIAxesPhotoRaw, 'Z')
            app.GalvanomtresTab = uitab(app.TabGroup2);
            app.GalvanomtresTab.Title = 'Galvanomètres';
            app.FactureTab = uitab(app.TabGroup);
            app.FactureTab.Title = 'Facture';
            app.UITable = uitable(app.FactureTab);
            app.UITable.ColumnName = {'Format'; 'Nom'; 'Prix'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @DmarrerlasimulationButtonPushed, true);
            app.TotalLabel = uilabel(app.FactureTab);
            app.TotalLabel.Text = 'Total :';
            app.TotalEditField = uieditfield(app.FactureTab, 'numeric');
            app.DerniercodebarresluLabel = uilabel(app.FactureTab);
            app.DerniercodebarresluLabel.Text = 'Dernier code-barres lu :';
            app.DerniercodebarresluEditField = uieditfield(app.FactureTab, 'text');
            app.AjoutmanuelEditField = uieditfield(app.FactureTab, 'text');
            app.AjoutmanueldarticlesButton = uibutton(app.FactureTab, 'push');
            app.AjoutmanueldarticlesButton.ButtonPushedFcn = createCallbackFcn(app, @AjoutmanueldarticlesButtonPushed, true);
            app.AjoutmanueldarticlesButton.Text = 'Ajout manuel d''articles';
            app.SauvegarderlafactureButton = uibutton(app.FactureTab, 'push');
            app.SauvegarderlafactureButton.Text = 'Sauvegarder la facture';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end